classdef uno < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        updatecardButton    matlab.ui.control.Button
        cardEditField       matlab.ui.control.EditField
        cardEditFieldLabel  matlab.ui.control.Label
        YourHandPanel       matlab.ui.container.Panel
        Image               matlab.ui.control.Image
    end

    
    properties (Access = private)
        numPlayers;
        playerTurn;
        topCard;
        playersNumCards;
        myPlayerNum;
        timestamp;
        channelID = 2364789;
        writeKey = 'I2UG9UCL0S6X3SCM';
        readKey = 'MJ8791RFGBHXVTG1';

    end
    
    properties (Access = public)
        deck;
        player;% Description
    end
    
    methods (Access = private)
        function [] = updateturn(app)
            %When calling this function provide both the current turn number from app
            %as well as the totalplayer number also from app
            if app.playerTurn < app.numPlayers
              app.playerTurn = app.playerTurn + 1;
            else
                app.playerTurn = 1;
            end
        end
        
        function writeData(app, timestamp)
            %This function requires an input timestamp date-time format.
            %the timestamp is the last time thingspeak was written to and
            %can be obtained from the function thingSpeakRead

            loop = true;
            while loop
                if (datetime("now") - timestamp) > duration(0,0,20)
                    thingSpeakWrite(app.channelID,[app.numPlayers, app.playerTurn,...
                        app.topCard,app.playersNumCards(1),app.playersNumCards(2),...
                        app.playersNumCards(3),app.playersNumCards(4)],...
                        "WriteKey",app.writeKey,"Timestamp",datetime("now"));
                    loop = false;
                else
                    pause(2);
                end
            end
        end
        
        function readData(app)
            [data,app.timestamp] = thingSpeakRead(app.channelID,"ReadKey",app.readKey);
            app.numPlayers = data(1);
            app.playerTurn = data(2);
            app.topCard = data(3);
            app.playersNumCards = [data(4), data(5), data(6), data(7)];
        end
        
        function gameLoop(app)
            playing = true;
            while playing
                app.readData;
                if app.numPlayers == 0
                    delete(app)
                end

                pause(2)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Read data in thingspeak server
            [data,timestamp] = thingSpeakRead(app.channelID,"ReadKey",app.readKey);

            numberplayers = data(1);
            playerturn = data(2);

            %initialize instances of the deck and players class
            app.deck = Deck();
            app.player = Player(app.deck);
            
            %Check if game is full or if game has already begun
            if (numberplayers >= 4) | (playerturn > 1)
                delete(app.UIFigure)
                return
            end
            %copy data from thingspeak server to app properties
            app.numPlayers = numberplayers + 1;
            app.myPlayerNum = numberplayers + 1;
            app.playerTurn = playerturn;
            app.topCard = data(3);
            app.playersNumCards = [data(4), data(5), data(6), data(7)];
            app.playersNumCards(app.myPlayerNum) = app.player.playerHandLength;

            %initialize a topcard if this is the first instance of the game
            %loaded
            if numberplayers == 0
                card = app.deck.pullCard();
                app.topCard = card.encodedCardNumber;
            end
            
            %write data to thingspeak
            app.writeData(timestamp)
        end

        % Button pushed function: updatecardButton
        function updatecardButtonPushed(app, event)
            pickedCard = app.deck.pullCard();


            app.Image.ImageSource = pickedCard.imageSource;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to end the game? (It may take a moment to close this tab)', 'Close request');
                if strcmpi(YN,'OK')
                    app.numPlayers = 0;
                    app.playerTurn = 1;
                    app.topCard = 0;
                    app.playersNumCards = zeros(1,4);
                    [~,timestamp] = thingSpeakRead(app.channelID,"ReadKey",app.readKey);
                    loop = true;
                    while loop
                        if (datetime("now") - timestamp) > duration(0,0,20)
                            thingSpeakWrite(app.channelID,[app.numPlayers, app.playerTurn,...
                            app.topCard,app.playersNumCards(1),app.playersNumCards(2),...
                            app.playersNumCards(3),app.playersNumCards(4)],...
                            "WriteKey",app.writeKey,"Timestamp",datetime("now"));
                            loop = false;
                            delete(app)
                        else
                            pause(2);
                        end
                        
                    end
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.YourHandPanel = uipanel(app.UIFigure);
            app.YourHandPanel.Title = 'Your Hand';
            app.Image = uiimage(app.YourHandPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UnoCards', 'blue0.png');
            app.cardEditFieldLabel = uilabel(app.UIFigure);
            app.cardEditFieldLabel.Text = 'card';
            app.cardEditField = uieditfield(app.UIFigure, 'text');
            app.updatecardButton = uibutton(app.UIFigure, 'push');
            app.updatecardButton.ButtonPushedFcn = createCallbackFcn(app, @updatecardButtonPushed, true);
            app.updatecardButton.Text = 'update card';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uno

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end